classdef Motion_Analysis_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DataPanel                    matlab.ui.container.Panel
        UIAxes3                      matlab.ui.control.UIAxes
        Slider                       matlab.ui.control.Slider
        ExportDataGeneratePDFButton  matlab.ui.control.Button
        LocalFrameDropDown           matlab.ui.control.DropDown
        LocalFrameDropDownLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        Flexion                      matlab.ui.control.UIAxes
        Abduction                    matlab.ui.control.UIAxes
        AxialRotation                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tsv
        t
        local_frame_string
    end
    
    methods (Access = private)
        % Plot all our created plots in the GUI
        function updatePlots(app)
            % Plot the whole man
            gui_3D_fullbody(app.tsv, app.t, app.UIAxes);
            % Plot the local_Frame
            gui_localframe_3d_lifetime(app.tsv, app.local_frame_string, app.UIAxes2);
            % Plot the angles
            plot_angles(app.tsv, app.local_frame_string, app.Flexion, app.Abduction, app.AxialRotation);
            % Plot spectrum analysis
            mean_x = sum(app.tsv{:,:}, 2);
            fs = 300;
            gui_spectrum_analysis(app.UIAxes3, mean_x, fs);
        end

        % Pop up to fill in personal details (generate PDF)
        function userDetails = promptUserDetails(app)
            % Define the prompts
            prompts = {'Age:', 'Gender:', 'Height:', 'Body Weight:', 'Years of Baseball:', 'Club:'};
            dlgTitle = 'Enter Personal Details';
            numLines = 1;
    
            % Show input dialog
            defaultAnswers = {'', '', '', '', '', ''};
            userInput = inputdlg(prompts, dlgTitle, numLines, defaultAnswers);
    
            % If user cancels, return empty
            if isempty(userInput)
                userDetails = [];
                return;
            end
    
            % Save user input
            userDetails.Age = userInput{1};
            userDetails.Gender = userInput{2};
            userDetails.Height = userInput{3};
            userDetails.Weight = userInput{4};
            userDetails.BaseballYears = userInput{5};
            userDetails.Club = userInput{6};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GridLayout.Scrollable = 'on';
            % Read the tsv file and store in app.tsv
            app.tsv = readtable("10Ax1.tsv","FileType","text","Delimiter","\t"); 
            % Set the slider for t, based on the tsv file length, and only
            % integers
            app.Slider.Limits = [1 height(app.tsv)];
            app.Slider.MajorTicks = round(linspace(1, height(app.tsv), 6));
            app.Slider.MajorTickLabels = string(app.Slider.MajorTicks);
            app.Slider.Value = 1;
            % Set out private properties to initial value
            app.t = app.Slider.Value;
            app.local_frame_string = app.LocalFrameDropDown.Value;
            % start all the plot methods
            app.updatePlots();
        end

        % Callback function
        function Part(app, event)
            %selectedPart = app.LocalFrameDropDown.Value;
        end

        % Callback function
        function showAngles(app, event)
            %plot_angles(app.tsv, app.LocalFrameDropDown.Value, app.Flexion, app.Abduction, app.AxialRotation);
        end

        % Callback function
        function plot_man(app, event)

        end

        % Callback function
        function showFlexionAngles(app, event)

        end

        % Callback function
        function show_angles(app, event)
            
        end

        % Callback function
        function plot_man_part(app, event)

        end

        % Value changed function: Slider
        function TimeSliderValueChanged(app, event)
            % Make an integer of the slider value, so we can actually use
            % it
            app.t = round(app.Slider.Value);
            app.updatePlots();
        end

        % Callback function
        function UIClose(app, event)

        end

        % Value changed function: LocalFrameDropDown
        function local_frame_change(app, event)
            app.local_frame_string = app.LocalFrameDropDown.Value;
            app.updatePlots();
        end

        % Callback function
        function Time(app, event)

        end

        % Button pushed function: ExportDataGeneratePDFButton
        function generatePDF(app, event)
            % Prompt user for personal details
            userDetails = app.promptUserDetails();
            % Prompt user to select a location and filename
            [file, path] = uiputfile('*.pdf', 'Save PDF As');
            if isequal(file, 0)
                % User cancelled
                return;
            end
            pdfFilePath = fullfile(path, file);
            
            % Create the first page (Personal details placeholder)
            fig = figure('Visible', 'off', 'Position', [100 100 1200 800]);
            fig.Color = 'w';
            axis off
            text(0.1, 0.8, 'Personal Details', 'FontSize', 20, 'FontWeight', 'bold');
            text(0.1, 0.6, ['Age: ', userDetails.Age], 'FontSize', 16);
            text(0.1, 0.5, ['Gender: ', userDetails.Gender], 'FontSize', 16);
            text(0.1, 0.4, ['Height (cm): ', userDetails.Height], 'FontSize', 16);
            text(0.1, 0.3, ['Body Weight (kg): ', userDetails.Weight], 'FontSize', 16);
            text(0.1, 0.2, ['Years of Baseball: ', userDetails.BaseballYears], 'FontSize', 16);
            text(0.1, 0.1, ['Club: ', userDetails.Club], 'FontSize', 16);
            % Save the first page
            exportgraphics(gcf, pdfFilePath, 'Append', false);
            close(fig);
            
            % Loop through all local frames and create a page per frame
            for i = 1:length(app.LocalFrameDropDown.Items)
                local_frame = app.LocalFrameDropDown.Items{i};
                
                % Create a new figure for each local frame
                fig = figure('Visible', 'off', 'Position', [100 100 1200 800]);
                fig.Color = 'w';

                % Add Vertical Divider between frames
                axes('Position', [0 0 1 1], 'Visible', 'off');
                rectangle('Position', [0 0.95 1 0.05], 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'none');
                text(0.5, 0.975, sprintf('Local Frame: %s', local_frame), 'HorizontalAlignment', 'center', 'FontSize', 16, 'FontWeight', 'bold', 'Color', 'w');
       
                % Create three subplots and pass them together to plot_angles
                ax1 = subplot(3,1,1);
                ax2 = subplot(3,1,2);
                ax3 = subplot(3,1,3);
                
                % Use the existing plot_angles function just like in your GUI
                plot_angles(app.tsv, local_frame, ax1, ax2, ax3);
                
                % Add titles to each subplot
                title(ax1, sprintf('%s - Flexion', local_frame), 'Interpreter', 'none');
                title(ax2, sprintf('%s - Abduction', local_frame), 'Interpreter', 'none');
                title(ax3, sprintf('%s - Axial Rotation', local_frame), 'Interpreter', 'none');
                
                % Save the current figure as the next page in the PDF
                exportgraphics(gcf, pdfFilePath, 'Append', true);
                close(fig);
            end
            
            % Notify the user
            uialert(app.UIFigure, 'Multi-page PDF has been successfully saved!', 'Success');
        end

        % Callback function: not associated with a component
        function inputDataSpectrum(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AxialRotation = uiaxes(app.GridLayout);
            title(app.AxialRotation, 'Axial Rotation Angles')
            xlabel(app.AxialRotation, 'X')
            ylabel(app.AxialRotation, 'Y')
            zlabel(app.AxialRotation, 'Z')
            app.Abduction = uiaxes(app.GridLayout);
            title(app.Abduction, 'Abduction Angles')
            xlabel(app.Abduction, 'X')
            ylabel(app.Abduction, 'Y')
            zlabel(app.Abduction, 'Z')
            app.Flexion = uiaxes(app.GridLayout);
            title(app.Flexion, 'Flexion Angles')
            xlabel(app.Flexion, 'X')
            ylabel(app.Flexion, 'Y')
            zlabel(app.Flexion, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '3D Body Part Plot')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '3D Man Plot')
            xlabel(app.UIAxes, 't')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LocalFrameDropDownLabel = uilabel(app.GridLayout);
            app.LocalFrameDropDownLabel.Text = 'Local Frame:';
            app.LocalFrameDropDown = uidropdown(app.GridLayout);
            app.LocalFrameDropDown.Items = {'upper_arm_m1', 'upper_arm_m2', 'lower_arm', 'thorax', 'pelvis', 'tigh', 'shin'};
            app.LocalFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @local_frame_change, true);
            app.LocalFrameDropDown.Value = 'upper_arm_m1';
            app.ExportDataGeneratePDFButton = uibutton(app.GridLayout, 'push');
            app.ExportDataGeneratePDFButton.ButtonPushedFcn = createCallbackFcn(app, @generatePDF, true);
            app.ExportDataGeneratePDFButton.Text = 'Export Data (Generate PDF)';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 600];
            app.Slider.MajorTicks = [100 200 300 400 500 600];
            app.Slider.MajorTickLabels = {'100', '200', '300', '400', '500', '600'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Data';
            app.UIAxes3 = uiaxes(app.DataPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motion_Analysis_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end